classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        HOMETab                   matlab.ui.container.Tab
        BASEDONTHEINFORMATIONYOUGIVELabel  matlab.ui.control.Label
        Image                     matlab.ui.control.Image
        Label                     matlab.ui.control.Label
        DETAILSABOUTTHEHOUSETab   matlab.ui.container.Tab
        EditField_2               matlab.ui.control.EditField
        Label_3                   matlab.ui.control.Label
        Lamp                      matlab.ui.control.Lamp
        EditField                 matlab.ui.control.NumericEditField
        PREDICTButton             matlab.ui.control.Button
        TYPEDropDown              matlab.ui.control.DropDown
        TYPEDropDownLabel         matlab.ui.control.Label
        TRANSACTIONDropDown       matlab.ui.control.DropDown
        TRANSACTIONDropDownLabel  matlab.ui.control.Label
        LOCALITYDropDown          matlab.ui.control.DropDown
        LOCALITYDropDownLabel     matlab.ui.control.Label
        PARKINGSpinner            matlab.ui.control.Spinner
        PARKINGSpinnerLabel       matlab.ui.control.Label
        STATUSDropDown            matlab.ui.control.DropDown
        STATUSDropDownLabel       matlab.ui.control.Label
        DISTRICTDropDown          matlab.ui.control.DropDown
        DISTRICTDropDownLabel     matlab.ui.control.Label
        LOCATIONDropDown          matlab.ui.control.DropDown
        LOCATIONDropDownLabel     matlab.ui.control.Label
        BEDROOMSpinner            matlab.ui.control.Spinner
        BEDROOMSpinnerLabel       matlab.ui.control.Label
        BHKSpinner                matlab.ui.control.Spinner
        BHKSpinnerLabel           matlab.ui.control.Label
        AreaSpinner               matlab.ui.control.Spinner
        AreaSpinnerLabel          matlab.ui.control.Label
        FURNISHINGDropDown        matlab.ui.control.DropDown
        FURNISHINGDropDownLabel   matlab.ui.control.Label
        Label_2                   matlab.ui.control.Label
        RDTABTab                  matlab.ui.container.Tab
        PlotGraphofTableButton    matlab.ui.control.Button
        ReadTableButton           matlab.ui.control.Button
        UITable                   matlab.ui.control.Table
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        t % Description
    end
    
    methods (Access = private)      
        function results = button(app)
            Area = app.AreaSpinner.Value;
            Parking = app.PARKINGSpinner.Value;
            BHK = app.BHKSpinner.Value;
            BedRoom = app.BEDROOMSpinner.Value;
            Result = (Area * (Parking + BHK + BedRoom)/2.3)*10.2635;
            
            app.Label_3.Text = 'The predicted price of the house is :';
            app.EditField_2.Value = 'Ethiopian Birr';
            app.EditField.Value = Result;
            
        end
    end
    
    methods (Access = public)
        
        function [trainedModel, validationRMSE] = trainRegressionModel(trainingData)
            
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PREDICTButton
        function PREDICTButtonPushed(app, event)
             button(app);
        end

        % Button pushed function: ReadTableButton
        function ReadTableButtonPushed(app, event)
            app.t = readtable("Mydata.xlsx","Sheet", 1);
            app.UITable.Data = app.t;
            app.UITable.ColumnName = app.t.Properties.VariableNames;
           
        end

        % Button pushed function: PlotGraphofTableButton
        function PlotGraphofTableButtonPushed(app, event)
            Area = table2array(app.t(:,"Area"));
            Price = table2array(app.t(:,"Price"));
            plot(app.UIAxes,Area,Price);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HOMETab = uitab(app.TabGroup);
            app.HOMETab.Title = 'HOME';
            app.Label = uilabel(app.HOMETab);
            app.Label.Text = 'HELLO, HUMAN, THIS IS A PRODUCT WHICH WILL PREDICT THE PRICES OF A HOUSE';
            app.Image = uiimage(app.HOMETab);
            app.Image.ImageSource = '960x0.jpg';
            app.BASEDONTHEINFORMATIONYOUGIVELabel = uilabel(app.HOMETab);
            app.BASEDONTHEINFORMATIONYOUGIVELabel.Text = ' BASED ON THE INFORMATION YOU GIVE.';
            app.DETAILSABOUTTHEHOUSETab = uitab(app.TabGroup);
            app.DETAILSABOUTTHEHOUSETab.Title = 'DETAILS ABOUT THE HOUSE';
            app.Label_2 = uilabel(app.DETAILSABOUTTHEHOUSETab);
            app.Label_2.Text = 'THE FOLLOWING ARE  DATAS YOU ARE REQUIRED TO ENTER IN ORDER TO PREDICT THE PRICE OF THE HOUSE';
            app.FURNISHINGDropDownLabel = uilabel(app.DETAILSABOUTTHEHOUSETab);
            app.FURNISHINGDropDownLabel.Text = 'FURNISHING';
            app.FURNISHINGDropDown = uidropdown(app.DETAILSABOUTTHEHOUSETab);
            app.FURNISHINGDropDown.Items = {'Furnished', 'Semi-Furnished', 'Unfurnished'};
            app.FURNISHINGDropDown.Value = 'Furnished';
            app.AreaSpinnerLabel = uilabel(app.DETAILSABOUTTHEHOUSETab);
            app.AreaSpinnerLabel.Text = 'Area';
            app.AreaSpinner = uispinner(app.DETAILSABOUTTHEHOUSETab);
            app.BHKSpinnerLabel = uilabel(app.DETAILSABOUTTHEHOUSETab);
            app.BHKSpinnerLabel.Text = 'BHK';
            app.BHKSpinner = uispinner(app.DETAILSABOUTTHEHOUSETab);
            app.BEDROOMSpinnerLabel = uilabel(app.DETAILSABOUTTHEHOUSETab);
            app.BEDROOMSpinnerLabel.Text = 'BEDROOM';
            app.BEDROOMSpinner = uispinner(app.DETAILSABOUTTHEHOUSETab);
            app.LOCATIONDropDownLabel = uilabel(app.DETAILSABOUTTHEHOUSETab);
            app.LOCATIONDropDownLabel.Text = 'LOCATION';
            app.LOCATIONDropDown = uidropdown(app.DETAILSABOUTTHEHOUSETab);
            app.LOCATIONDropDown.Items = {'Karol Bagh', 'Okhala', 'Laxmi Nagar', 'Vasundhara', 'Dilshad Garden', 'Geeta Colony', 'Budh Vihar', 'Rohini', 'Narela', 'Shahadara', 'Chhattarapur', 'Chittaranjan Park', 'Friends Colony', 'Malviya Nagar', 'Haus Khas', 'Lajpat Nagar', 'Mahavir Enclave', 'Mehrauli', 'Greater Kailash', 'Alaknanda', 'Sheikh Sarai', 'Saket', 'Alkananda', 'Kalkaji', 'Safdarjung Enclave', 'Vasant Kunj', 'Vasant Kunj', 'Janakpuri', 'Manglapuri', 'Sultanpur', 'Dwarka', 'Paschim Vihar', 'Moti Nagar', 'Patel Nagar', 'Punjabi Bagh', 'Paschim Vihar', 'Uttam Nagar', 'Laxmi Nagar', 'Friends Colony'};
            app.LOCATIONDropDown.Value = 'Karol Bagh';
            app.DISTRICTDropDownLabel = uilabel(app.DETAILSABOUTTHEHOUSETab);
            app.DISTRICTDropDownLabel.Text = 'DISTRICT';
            app.DISTRICTDropDown = uidropdown(app.DETAILSABOUTTHEHOUSETab);
            app.DISTRICTDropDown.Items = {'Central Delhi', 'East Delhi', 'North West Delhi', 'Shahadara', 'South Delhi', 'South East Delhi', 'South West Delhi', 'West Delhi', 'Central Delhi', 'East Delhi'};
            app.DISTRICTDropDown.Value = 'Central Delhi';
            app.STATUSDropDownLabel = uilabel(app.DETAILSABOUTTHEHOUSETab);
            app.STATUSDropDownLabel.Text = 'STATUS';
            app.STATUSDropDown = uidropdown(app.DETAILSABOUTTHEHOUSETab);
            app.STATUSDropDown.Items = {'Ready_to_move', 'Almost_ready'};
            app.STATUSDropDown.Value = 'Ready_to_move';
            app.PARKINGSpinnerLabel = uilabel(app.DETAILSABOUTTHEHOUSETab);
            app.PARKINGSpinnerLabel.Text = 'PARKING';
            app.PARKINGSpinner = uispinner(app.DETAILSABOUTTHEHOUSETab);
            app.LOCALITYDropDownLabel = uilabel(app.DETAILSABOUTTHEHOUSETab);
            app.LOCALITYDropDownLabel.Text = 'LOCALITY';
            app.LOCALITYDropDown = uidropdown(app.DETAILSABOUTTHEHOUSETab);
            app.LOCALITYDropDown.Items = {'''Abul Fazal Enclave Part-II, Okhla''', '''The Amaryllis, Karol Bagh'''};
            app.LOCALITYDropDown.Value = '''Abul Fazal Enclave Part-II, Okhla''';
            app.TRANSACTIONDropDownLabel = uilabel(app.DETAILSABOUTTHEHOUSETab);
            app.TRANSACTIONDropDownLabel.Text = 'TRANSACTION';
            app.TRANSACTIONDropDown = uidropdown(app.DETAILSABOUTTHEHOUSETab);
            app.TRANSACTIONDropDown.Items = {'''Resale''', '''New_Property'''};
            app.TRANSACTIONDropDown.Value = '''Resale''';
            app.TYPEDropDownLabel = uilabel(app.DETAILSABOUTTHEHOUSETab);
            app.TYPEDropDownLabel.Text = 'TYPE';
            app.TYPEDropDown = uidropdown(app.DETAILSABOUTTHEHOUSETab);
            app.TYPEDropDown.Items = {'''Builder_Floor''', '''Apartment'''};
            app.TYPEDropDown.Value = '''Builder_Floor''';
            app.PREDICTButton = uibutton(app.DETAILSABOUTTHEHOUSETab, 'push');
            app.PREDICTButton.ButtonPushedFcn = createCallbackFcn(app, @PREDICTButtonPushed, true);
            app.PREDICTButton.Text = 'PREDICT';
            app.EditField = uieditfield(app.DETAILSABOUTTHEHOUSETab, 'numeric');
            app.Lamp = uilamp(app.DETAILSABOUTTHEHOUSETab);
            app.Label_3 = uilabel(app.DETAILSABOUTTHEHOUSETab);
            app.Label_3.Text = '.';
            app.EditField_2 = uieditfield(app.DETAILSABOUTTHEHOUSETab, 'text');
            app.RDTABTab = uitab(app.TabGroup);
            app.RDTABTab.Title = '3RD TAB';
            app.UIAxes = uiaxes(app.RDTABTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Area')
            ylabel(app.UIAxes, 'Price')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.RDTABTab);
            app.UITable.ColumnName = {''; ''; ''; ''};
            app.UITable.RowName = {};
            app.ReadTableButton = uibutton(app.RDTABTab, 'push');
            app.ReadTableButton.ButtonPushedFcn = createCallbackFcn(app, @ReadTableButtonPushed, true);
            app.ReadTableButton.Text = 'Read Table';
            app.PlotGraphofTableButton = uibutton(app.RDTABTab, 'push');
            app.PlotGraphofTableButton.ButtonPushedFcn = createCallbackFcn(app, @PlotGraphofTableButtonPushed, true);
            app.PlotGraphofTableButton.Text = 'Plot Graph of Table';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end